classdef MetametricApp_Beta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        DataImportTab                   matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        UITable_ImportedFile            matlab.ui.control.Table
        Panel3                          matlab.ui.container.Panel
        Button_UseSelectedSPDs          matlab.ui.control.Button
        EditField_ImportedFileName      matlab.ui.control.EditField
        Button_ImportTable              matlab.ui.control.Button
        DataValidationTab               matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        UIAxes_ImportedSPDs             matlab.ui.control.UIAxes
        UIAxes_ChromDiagram             matlab.ui.control.UIAxes
        OptimizationSettingsTab         matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        OptimizationConstraintsPanel    matlab.ui.container.Panel
        UITable_Constraints             matlab.ui.control.Table
        ProgramPathPanel                matlab.ui.container.Panel
        EditField_OutputFileToSaveTo    matlab.ui.control.EditField
        OutputExcelFileNameLabel        matlab.ui.control.Label
        EditField_OutputFolder          matlab.ui.control.EditField
        CurrentOutputFolderEditFieldLabel  matlab.ui.control.Label
        Button_SetOutputFolder          matlab.ui.control.Button
        OptimizationSettingsPanel       matlab.ui.container.Panel
        Button_RunOptimization          matlab.ui.control.Button
        EditField_MaxIterations         matlab.ui.control.NumericEditField
        MaxOptimizationIterationsLabel  matlab.ui.control.Label
        OptimizationGoalPanel           matlab.ui.container.Panel
        DropDown_MetricGoal             matlab.ui.control.DropDown
        WhichMetricLabel                matlab.ui.control.Label
        DropDown_MaxOrMinGoal           matlab.ui.control.DropDown
        MaximizeOrMinimizeLabel         matlab.ui.control.Label
        OptimizationResultsTab          matlab.ui.container.Tab
        Panel4                          matlab.ui.container.Panel
        UIAxes_OptimSPD                 matlab.ui.control.UIAxes
        UIAxes_TM30Bins                 matlab.ui.control.UIAxes
        UIAxes_TM30Vector               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        outputSavePath; % Description
        OutputFolder = 'SolutionOutputs'; %We save our file to this folder
        ScalePerSPD % Description
        appRootPath % Description
    end
    
    properties (Access = public)
        %% Imported Raw Values
        userSPDsImported_Prop; % Description
        XYZUserSPDsImported_Prop;
        importedFileName_Prop;
        xyuserSPDsImported_Prop;
        wlIntUserImported_Prop

        SPDsScaled_Prop ;
        xySPDsScaled_Prop ;
        XYZSPDsScaled_Prop;
        OptimizationRunStopTF_Prop;
        %% Optimization options
        % Max/Min Goal Type
%         myUiFun_Prop;
%         myUiCon_Prop;
        InitialGuessChannelPercents_Prop;

        AppFilePath;
    end
    
    methods (Access = private)
        
        function PlotSPDs_Helper(app)
            warning on backtrace
            plot(app.UIAxes_ImportedSPDs, 380:app.wlIntUserImported_Prop:780, app.userSPDsImported_Prop(:,1:end));
            app.UIAxes_ImportedSPDs.XLim = [380,780];
        end
        
        function PlotSPDChromDiagram_Helper(app)
            warning on backtrace
            %% Rename the functions argments to be the app's properties. 
            % This is verbose but might help understand code better
            %% Add the xy coordinates of the spds to the chrom diagram plot
            scatter(app.UIAxes_ChromDiagram, app.xyuserSPDsImported_Prop(:,1), app.xyuserSPDsImported_Prop(:,2) ); %plot them
            %% Don't overwrite plot each time a new plot is added
            % previous ones Note that using "hold on" by itself will NOT work https://www.mathworks.com/help/matlab/ref/uiaxes.html
            hold(app.UIAxes_ChromDiagram,'on'); 
            %% Set axis aspect ratio to be square. This is same as "axis square"
            pbaspect(app.UIAxes_ChromDiagram,[1 1 1]);
            %% Add the spectral locus and blackbody locus
            plotChromDiagram(2,app.UIAxes_ChromDiagram); %add a 2 deg chrom diagram to the axis handle
            
        end
        
        function PlotRecentTM30Bins(app,myOptimResults)
%             % Called at the end of "run optimization"
%             %% Assume that we want to plot the most recent optim results
%             myRecentResults = myOptimResults(end);
%             hold(app.UIAxes_TM30Bins,'on');
% 
%             csBins = myRecentResults.metrics.csBins;
%             hsBins = myRecentResults.metrics.hsBins;
%             rfBins = myRecentResults.metrics.rfBins;
%             %% Create / Recreate tiledlayout for the axes
%             tl = tiledlayout(app.UIAxes_TM30Bins,3,1);
%             %% Create each tile
%             rfTile = nexttile(tl);
%             rfBinBar = bar(rfTile, [1:16], rfBins);
% 
%             csTile = nexttile(tl);
%             csBinBar = bar(csTile, csBins);
% 
%             hsTile = nexttile(tl);
%             hsBinBar = bar(hsTile, hsBins);
% 
%             %% Format each tile now. This could be condensed later or made a function
%             % rfTile
%             xTip1 = rfBinBar(1).XEndPoints;
%             yTip1 = rfBinBar(1).YEndPoints;
%             labels = string( round(rfBinBar(1).YData,2,'significant') );
%             text(rfBinBar,xTip1,yTip1,labels, 'HorizontalAlignment','center','VerticalAlignment','bottom');
%             ylabel(rfBinBar,'rf bins')
%             rfTile.XTick = [1:16];
%             
%             % csTile
%             xTip1 = csBinBar(1).XEndPoints;
%             yTip1 = csBinBar(1).YEndPoints;
%             labels = string(csBinBar, round(csBinBar(1).YData,2,'significant') );
%             text(csBinBar,xTip1,yTip1,labels, 'HorizontalAlignment','center','VerticalAlignment','bottom');
%             ylabel(csBinBar,'cs bins')
%             csTile.XTick = [1:16];
% 
%             % hsTile
%             xTip1 = hsBinBar(1).XEndPoints;
%             yTip1 = hsBinBar(1).YEndPoints;
%             labels = string( round(hsBinBar(1).YData,2,'significant') );
%             text(hsBinBar,xTip1,yTip1,labels, 'HorizontalAlignment','center','VerticalAlignment','bottom');
%             ylabel(hsBinBar,'hs bins')
%             hsTile.XTick = [1:16];

        end
        
        function PlotTM30VectorGraphic(app,myOptimResults)
            %% Again, assume we want to plot the most recent results
            myRecentResults = myOptimResults(end);
            gref = myRecentResults.metrics.gref;
            gtest = myRecentResults.metrics.gtest;
            %%
            hold(app.UIAxes_TM30Vector,'on');
            title(app.UIAxes_TM30Vector,"Hue Angle Plot");
            HueAngleAvgRefP  = plot(app.UIAxes_TM30Vector, [gref(:,1);gref(1,1)] , [gref(:,2); gref(1,2)],'-k');
            HueAngleAvgTestP = plot(app.UIAxes_TM30Vector, [gtest(:,1);gtest(1,1)] , [gtest(:,2);gtest(1,2)], '-or');
            legend(app.UIAxes_TM30Vector,[HueAngleAvgRefP, HueAngleAvgTestP],...
                {"Final Hue Angle Coords Reference", "Final hue Angle Coords Test"});
            xlabel(app.UIAxes_TM30Vector,"a" + "'");
            ylabel(app.UIAxes_TM30Vector,"b" + "'");
            app.UIAxes_TM30Vector.XTick = [app.UIAxes_TM30Vector.XLim(1):5:app.UIAxes_TM30Vector.XLim(2)];
            app.UIAxes_TM30Vector.YTick = [app.UIAxes_TM30Vector.YLim(1):5:app.UIAxes_TM30Vector.YLim(2)];
            axis square
        end
        
        function WriteOptimResults(app, myOptimResults)
            %% I practiced this in "practiceSaveResultsToFile.mlx"
            % List out which metrics to delete
            metricDeleteList = {'sref','stest','stest0','r2XYZ',...
                'r10XYZ','IterationStop','rfBins','hsBins','csBins',...
                'gref','gtest','SpdPercents0','Solution','s','wl'};
            % Create scalar struct (size 1) that will be output to file by
            % deleting fields listed above
            myStructForTable = rmfield(myOptimResults.metrics,metricDeleteList);
            %% Create set of tables
            % This is the main table that has single-entry values
            myTable = struct2table(myStructForTable,'AsArray',true);
            varsToSplit = {'t2XYZ', 't2xy','t10XYZ','t10xy'};
            splitNames =  { {'2DegX', '2DegY','2DegZ'}, {'2Degx','2Degy'} ,...
                {'10DegX','10DegY','10DegZ'}, {'10Degx','10Degy'} };
            myTable = splitvars(myTable, varsToSplit, 'NewVariableNames',splitNames);
            % 
            myUiFunTable = struct2table(myOptimResults.myUiFun,'AsArray',true);
            if myUiFunTable.minOrMax == -1
                myUiFunTable.minOrMax = "Maximize";
            elseif myUiFunTable.minOrMax == 1
                myUiFunTable.minOrMax = "Minimize";
            else
%                 error("Unknown goal type (min or max) when writing myUiFun to table");
                msgbox("Unknown goal type (min or max) when writing myUiFun to table",'Error','error');
            end


            % SPD Watts/nm
            spdTable = splitvars(table(myOptimResults.metrics.s'));
            spdTable.Properties.VariableNames = cellstr("Wavelength(nm): " +...
                string(myOptimResults.metrics.wl) );
            % The mixture of channels used for solution
            solutionTable = splitvars(table(myOptimResults.metrics.Solution'));
            solutionTable.Properties.VariableNames = cellstr( "Channel "...
                + string( 1:numel(myOptimResults.metrics.Solution) ) + " %" );
            % The initial guess percent for each channel
            initialGuessTable = splitvars(table(myOptimResults.metrics.SpdPercents0') );
            initialGuessTable.Properties.VariableNames = cellstr( "Channel " +...
                string( 1:numel(myOptimResults.metrics.SpdPercents0)) + " Initial Guess %") ;
            % How many iterations were used for optimization run
            iterationUsedTable = table(myOptimResults.metrics.IterationStop);
            iterationUsedTable.Properties.VariableNames = "Iterations Used";
            %% We have all of our tables as 1 row and many variables. Merge them
            myTableOneCol = [iterationUsedTable, myUiFunTable,myTable, solutionTable,...
                initialGuessTable, spdTable];
            %% Make each run correspond to column instead of a row
            % SPDs are usually written column-wise 
            myTableOneCol = rows2vars(myTableOneCol);
            % By default it make variable names a column on its own. Make
            % and assign rownames of table to the variable names then
            % delete the unused column of variable names now that they are
            % row namesmyTableOneCol
            myTableOneCol.Properties.RowNames= myTableOneCol.OriginalVariableNames;
            myTableOneCol.OriginalVariableNames = [];
            %% Get optimization settings to put into a third sheet
            myProps = properties(myOptimResults.myOptimOptions);
            for prop = 1:numel(myProps)
              optionStruct.(myProps{prop}) = myOptimResults.myOptimOptions.(myProps{prop});
            end
            optionTable = splitvars( struct2table(optionStruct,'asarray',true))  ;
            optionTable.TypicalX = [];
            typicalXTable= splitvars(table(myOptimResults.myOptimOptions.TypicalX') );
            typicalXTable.Properties.VariableNames = "Typical X, CH " + string(1:numel(myOptimResults.myOptimOptions.TypicalX) );
            
            optionTable = rows2vars( [optionTable, typicalXTable] ) ;
            %% Ask user where to save
            % This code is very similar to the one used to import the data
            importFileTypes = {'*.xls;*.xlsx;*.xlsb;*.xlsm;*.csv',...
                 'SPD Files (*.xls,*.xlsx,*.xlsb,*.xlsm,*.csv)'};
            [outputFileName, outputPathName] = uiputfile(importFileTypes);
            app.outputSavePath = fullfile(outputPathName,outputFileName);
            %% If file exists, load existing runs as table and append this run to it and resave
            % Note that we output 2 sheets. First sheet is the metrics per
            % run and 2nd sheet is the metric constraints table. 
            
            switch exist(app.outputSavePath,'file')
                case 0 %file does not exist yet
                    %write out merged table
                    myTableOneCol.Properties.VariableNames = "Run 1";
                    writetable(myTableOneCol,...
                        app.outputSavePath, 'Sheet',1,'WriteRowNames',true,...
                        'WriteVariableNames',true);

                    writetable(myOptimResults.myConstraintsTable,...
                        app.outputSavePath, 'Sheet',2);

                    writetable(optionTable,...
                        app.outputSavePath, 'Sheet',3);
                case 2 %it exists and is a file
                    existingTable = readtable(app.outputSavePath,'Sheet',1,'ReadRowNames',true);
                    
                    myTableOneCol.Properties.VariableNames = "Run" + string(size(existingTable,2) +1);
                    myTableAppended = [existingTable, myTableOneCol];
                    
                    writetable(myTableAppended,...
                        app.outputSavePath, 'Sheet',1,'WriteRowNames',true,...
                        'WriteVariableNames',true);
                    % Check if we use same constraints
%                     existingContraintstable = readtable(app.outputSavePath, 'Sheet',1);
                    
                otherwise
%                     error("Unexpected case when checking is the output file already exists");
                    msgbox("Unexpected case when checking is the output file already exists",'Error','error');
            end

        end
        
        function PlotOptimSPD(app,myOptimResults)
            plot(app.UIAxes_OptimSPD,...
                myOptimResults.metrics.wl, myOptimResults.metrics.s);
        end

        function saveMyDefaults(app)
            %write values to myDefaults before saving
            myDefaults.OutputFolder = app.EditField_OutputFolder.Value;
%             myDefaults.
            save('MyAppDefaultValues.mat','myDefaults');
        end
        
        function loadMyDefaults(app)
            load('MyAppDefaultValues.mat','myDefaults');
            app.OutputFolder = myDefaults.OutputFolder;
            app.EditField_OutputFolder.Value = myDefaults.OutputFolder;
        end
        
        function [SpdMixOut, myOptimOptions,iterationStop,myUiFun] = RunOptimization(app)
                %% Options and edit fields are called only on this button press

                % Get table from uitable
                metricConstraints_table = app.UITable_Constraints.Data; %what is diff between Data & DisplayData field?
                

                %% Run Optimization

                % Make a random guess
                app.InitialGuessChannelPercents_Prop = 25+75*rand(width(app.SPDsScaled_Prop),1);
                spdPercents0 = app.InitialGuessChannelPercents_Prop;

                %
                spdChannels = app.SPDsScaled_Prop;
                % Make myUiFun structure
                myUiFun.myOptIterations = app.EditField_MaxIterations.Value;
                myUiFun.metric = string(app.DropDown_MetricGoal.Value);
                switch app.DropDown_MaxOrMinGoal.Value
                    case 'Maximize'
                        myUiFun.minOrMax = -1;
                    case 'Minimize'
                        myUiFun.minOrMax = 1;
                end
                %% Run optimizer
                appStopFlag = false; %app.OptimizationRunStopTF_Prop;
                [SpdMixOut, myOptimOptions,iterationStop] = Main_Optimizer_Function_App_mfile(spdPercents0, spdChannels, metricConstraints_table,...
                    myUiFun,appStopFlag);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Load previous session's output folder
            try
                app.loadMyDefaults;
            catch
            end
            
            %% Define folder where the output excel table goes
%             app.saveFolder = 'SolutionOutputs';
            % If the folder does not exist, make it.
%             if exist(app.OutputFolder,'dir') ~= 7
%                 mkdir(app.OutputFolder);
%             end
            % Find the parent folder this app is in
            [app.appRootPath] = fileparts( mfilename('fullpath') );
%             app.AppFilePath = myRoot; %set app prop to remember
%             app.EditField_AppRoot.Value = myRoot; %output path for debug


            %%
            hold(app.UIAxes_OptimSPD,'on');
            title(app.UIAxes_OptimSPD, "Most Recent SPD");
            xlabel(app.UIAxes_OptimSPD, "Wavelength (nm)");
            ylabel(app.UIAxes_OptimSPD, "Rad Watt / nm");
            app.UIAxes_ImportedSPDs.XLim = [380,780];


            %%
            app.OptimizationRunStopTF_Prop = false;
            %% Enable Imported Data uitable to select

            %% Create Default Optimization Constraint Table
            % Name list of metrics and defaults
            Metrics       = {'rf'; 'rg'; 'cct';...
                'duv'; 'sOpicDER'; 'mOpicDER';...
                'lOpicDER';'melOpicDER'; 'rOpicDER'};
            UseTF    = false( numel(Metrics),1);
            LessThanTF    = false(numel(Metrics),1);
            EqualToTF     = true(numel(Metrics),1); %default
            GreaterThanTF =false(numel(Metrics),1 );

            LessThanVal    = 1*inf*ones( numel(Metrics), 1);
            EqualToVal     = [70; 70; 6500; 0; 1; 1; 1; 1; 1]; %default
            GreaterThanVal = -1*inf*ones( numel(Metrics),1);
            % Create a standard table
            constraints_Table = table(Metrics, UseTF, LessThanTF, LessThanVal, EqualToTF, EqualToVal, GreaterThanTF,GreaterThanVal);
            % Fill in the uitable 
            app.UITable_Constraints.Data = constraints_Table;
            app.UITable_Constraints.ColumnName = constraints_Table.Properties.VariableNames;
            app.UITable_Constraints.ColumnEditable = true;
            app.UITable_Constraints.Multiselect = true;
%             app.UITable_Constraints.ColumnWidth = 'fit';
            app.UITable_Constraints.FontSize = 18;
            app.UITable_Constraints.RowStriping ='on';
            
            styleCentered = uistyle('HorizontalAlignment','center');
            addStyle(app.UITable_Constraints, styleCentered, 'column', [2:width(app.UITable_Constraints.Data)] );
            
            %% Set options for Optimization Goal Drop Downs
            app.DropDown_MetricGoal.Items = Metrics;
            app.DropDown_MaxOrMinGoal.Items = {'Maximize','Minimize'};
            %% Set options for Optimization Constraitns Drop Downs
            
            %% Set default max iterations
            app.EditField_MaxIterations.Value = 500;

            %% Set default file to save to

            %app.EditField_OutputFileToSaveTo.Value = "defaultOutputSaveFile";
        end

        % Button pushed function: Button_ImportTable
        function Button_ImportTablePushed(app, event)

            %% Nothing here is shared outside of this function unless it is added to a property in the block below
            %%Deal with ui callback
            %https://www.youtube.com/watch?v=7_IC8Xcjp4w
            % https://www.mathworks.com/help/matlab/ref/uigetfile.html#mw_fbfe34a8-3ccf-49de-b27f-ff95c81b212e

            importFileTypes = {'*.xls;*.xlsx;*.xlsb;*.xlsm;*.csv',...
                 'SPD Files (*.xls,*.xlsx,*.xlsb,*.xlsm,*.csv)'};
            [spdFileName,spdFolderPath] = uigetfile(importFileTypes); %have user select csv file
            
            %% Get full path of file
            spdFileFullPath =  fullfile(spdFolderPath,spdFileName);
            app.importedFileName_Prop = spdFileName;
            %% Import the data into uitable
            try
                mySheetNames = sheetnames(spdFileFullPath);
                t = readtable(spdFileFullPath,"Sheet",mySheetNames(1),'PreserveVariableNames',true );
            catch
                t = readtable(spdFileFullPath,'PreserveVariableNames',true );
            end

            %% Check that all values are positive
            if any(t{:,:} < 0,'all') % check 'all' values to see if any are < 0
                % https://www.mathworks.com/help/matlab/ref/uialert.html
                [rowIdx, colIdx] = find(  t{:,:} < 0 ); %get row and column indices that are negative
                colList = unique(colIdx); %get the unique list of columns that had a negative

                message = [(string(numel(colIdx)) + " Entries were found to be negative");...
                    "The following columns (SPDs) had negatives: " + strjoin( string(colList), ',');... %this just makes a list like colList = [1,3,4] ---> "1, 3, 4"
                    "This/these value(s) will be set to 0 in the app";...
                    "(The file used to import data will not be changed)"];
                uialert(app.UIFigure, message,'Warning','Icon','warning');

                t{rowIdx,colIdx} = 0; %set the negative values to 0
            end
            %% Write cleaned data to the table
            app.UITable_ImportedFile.Data = t;
            % Also make the columns editable
%             app.UITable_ImportedFile.ColumnName = 'numbered';
            %% Put the imported file name in the edit field
            % After selection it gets changed to show many many channels,
            % but it should also be here
            app.EditField_ImportedFileName.Value = "Data Loaded From "  + string(app.importedFileName_Prop);

        end

        % Button pushed function: Button_RunOptimization
        function Button_RunOptimizationPushed(app, event)
            try
%                 %% Find path file to save to
%                 % Check if save file already exists, and assume we want to append to it
%                 saveFileName = strcat( app.EditField_OutputFileToSaveTo.Value); % The name
%                 app.outputSavePath =  fullfile(app.OutputFolder,saveFileName) +".xls";


% %                 %% Options and edit fields are called only on this button press
% % 
% %                 % Get table from uitable
% %                 metricConstraints_table = app.UITable_Constraints.Data; %what is diff between Data & DisplayData field?
% %                 
% % 
% %                 %% Run Optimization
% % 
% %                 % Make a random guess
% %                 app.InitialGuessChannelPercents_Prop = 25+75*rand(width(app.SPDsScaled_Prop),1);
% %                 spdPercents0 = app.InitialGuessChannelPercents_Prop;
% % 
% %                 %
% %                 spdChannels = app.SPDsScaled_Prop;
% %                 % Make myUiFun structure
% %                 myUiFun.myOptIterations = app.EditField_MaxIterations.Value;
% %                 myUiFun.metric = string(app.DropDown_MetricGoal.Value);
% %                 switch app.DropDown_MaxOrMinGoal.Value
% %                     case 'Maximize'
% %                         myUiFun.minOrMax = -1;
% %                     case 'Minimize'
% %                         myUiFun.minOrMax = 1;
% %                 end
% %                 %% Run optimizer
% %                 appStopFlag = false; %app.OptimizationRunStopTF_Prop;
% %                 [SpdMixOut, myOptimOptions,iterationStop] = Main_Optimizer_Function_App_mfile(spdPercents0, spdChannels, metricConstraints_table,...
% %                     myUiFun,appStopFlag);
                
                %% Run optimization
                [SpdMixOut, myOptimOptions,iterationStop,myUiFun] = RunOptimization(app);
                %% Store results into one scalar structure
                myOptimResults(1).metrics = SpdMixOut;
                myOptimResults(1).spdPercents0 = app.InitialGuessChannelPercents_Prop;
                myOptimResults(1).myUiFun = myUiFun;
                myOptimResults(1).myOptimOptions = myOptimOptions;
                myOptimResults(1).myConstraintsTable = app.UITable_Constraints.Data;
                myOptimResults(1).spdFileImportPath =  app.importedFileName_Prop;
                myOptimResults(1).iterationStop = iterationStop;
                %% Check that it is possible
                if min(myOptimResults(1).metrics.s < 0)
                    message = {'SPD Result Is Not All-Positive', 'No Data Will Be Saved Or Plotted', 'Try again'};
                    uialert(app.UIFigure,message,'Error','Icon','Error');
                    return
                end
                %% Display result and ask for selection
                %https://www.mathworks.com/help/matlab/ref/uiconfirm.html
                msg = ["Goal Metric, " + myUiFun.metric + ", is " + string(myOptimResults(1).metrics.(myUiFun.metric) );...
                    "Would you like to save this SPD?"];
                selection = uiconfirm(app.UIFigure, msg,"Save Results?", 'Options', {'Yes', 'No', 'Try More'} );
                switch selection
                    case 'Yes'
                        %WriteOptimResults also prompts user for where to
                        %save. Consider breaking up the function later
                        WriteOptimResults(app, myOptimResults); 
                        PlotTM30VectorGraphic(app,myOptimResults);
                        PlotRecentTM30Bins(app,myOptimResults);
                        PlotOptimSPD(app, myOptimResults);
                    case 'No'
                        msg = ["You chose not to save the results"; "You can change the inputs and click 'run' again"];
                        uialert(app.UIFigure, msg, 'Unsatisfactory Results', 'Interpreter','html','icon','info');

                        return
                    case 'Try More'
                        error("Not Implemented")
                    otherwise
                        error("Case errorin selecting how to proceed with optimization results")
                end

                %% Ask user for save file

                %% Write results
%                 WriteOptimResults(app, myOptimResults);
%                 %% Plot Data
%                 WriteOptimResults(app, myOptimResults);
%                 PlotTM30VectorGraphic(app,myOptimResults);
%                 PlotRecentTM30Bins(app,myOptimResults);
%                 PlotOptimSPD(app, myOptimResults);
                %%

                %%
% %                 if min( myOptimResults(1).metrics.s >=0)
% %                     WriteOptimResults(app, myOptimResults);
% %                     PlotTM30VectorGraphic(app,myOptimResults);
% %                     PlotRecentTM30Bins(app,myOptimResults);
% %                     PlotOptimSPD(app, myOptimResults)
% %                 else
% %                     message = {'SPD Result Is Not All-Positive', 'No Data Will Be Saved Or Plotted', 'Try again'};
% %                     uialert(app.UIFigure,message,'Error','Icon','Error');
% %                 end
                %%
            catch ME
                report = getReport(ME);
                uialert(app.UIFigure, report, 'Error Message', 'Interpreter','html')
            end
            %% If try block succeeded, prompt save
        end

        % Button pushed function: Button_UseSelectedSPDs
        function Button_UseSelectedSPDsPushed(app, event)
%             warning backtrace on
            %% Select SPDs from selection

            % the .Selection queries the highlighted cells and returns an
            % n x 2 array of indices: [rows, cols]. Use this to create a
            % subtable and then convert that to an array
            cellSelection = app.UITable_ImportedFile.Selection;
            uniqueRow = unique(cellSelection(:,1) ); %note that unique automatically sorts in ascending order
            uniqueCol = unique(cellSelection(:,2) );
            
            subTableSelected = app.UITable_ImportedFile.Data(uniqueRow,uniqueCol); %selected set of SPDs
            app.userSPDsImported_Prop = table2array( subTableSelected ); %my functiosn expect arrays

            app.wlIntUserImported_Prop = (780-380)/(height(app.userSPDsImported_Prop)-1);
            if round( app.wlIntUserImported_Prop) ~= app.wlIntUserImported_Prop
%                 error()
                msgbox( "When guessing wavelength interval, a non-integer was determined. "+...
                    "Are you sure the wavelengths are from 380 to 780 and that you selected the entire column?", 'Error','error')
            end
            % This Ensure they chose a square selection (not necessarily
            % contiguous). 
            if numel(app.userSPDsImported_Prop) ~= size(cellSelection,1)
%                 error("Please Ensure each SPD has the same amount of rows selected")
                msgbox("Please Ensure each SPD has the same amount of rows selected", 'Error','error');
            end

            %% Set the user spds to the selected subtable
            
            observerChoice = 2;
            [app.xyuserSPDsImported_Prop,app.XYZUserSPDsImported_Prop] = spdsToXyXYZ(app.userSPDsImported_Prop(:,1:end), observerChoice );%get xy chromaticities for the imported spds

            %% Scale data Y10 = 100 for each channel
            % Find amount needed to scale each channel
            app.ScalePerSPD = 100./app.XYZUserSPDsImported_Prop(:,2);
            % Create new set of SPDs that are scaled to Y10 = 100;
            app.SPDsScaled_Prop         = app.ScalePerSPD'.*app.userSPDsImported_Prop; 
            % Create new set of XYZ for them scaled. xy should not change
            % via scaling
            [app.xySPDsScaled_Prop,app.XYZSPDsScaled_Prop] = spdsToXyXYZ(app.SPDsScaled_Prop(:,1:end) , 2 );%get xy chromaticities for the imported spds

            %% Set edit value text box to show file name and number of channels
            app.EditField_ImportedFileName.Value = num2str(width(app.userSPDsImported_Prop)) + " channels were loaded from: "  + string(app.importedFileName_Prop);

            %% Plot the chrom diagram
            PlotSPDChromDiagram_Helper(app);
            %% Plot raw imported data to spd figure
            PlotSPDs_Helper(app);
        end

        % Button pushed function: Button_SetOutputFolder
        function Button_SetOutputFolderPushed(app, event)
%             app.EditField_OutputFolder.Value = uigetdir;
            app.OutputFolder = uigetdir;
            app.EditField_OutputFolder.Value = app.OutputFolder;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.saveMyDefaults;

            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataImportTab = uitab(app.TabGroup);
            app.DataImportTab.Title = 'Data Import';
            app.GridLayout3 = uigridlayout(app.DataImportTab);
            app.Panel3 = uipanel(app.GridLayout3);
            app.Panel3.Title = 'Panel3';
            app.Button_ImportTable = uibutton(app.Panel3, 'push');
            app.Button_ImportTable.ButtonPushedFcn = createCallbackFcn(app, @Button_ImportTablePushed, true);
            app.Button_ImportTable.Text = 'Import Table';
            app.EditField_ImportedFileName = uieditfield(app.Panel3, 'text');
            app.EditField_ImportedFileName.Placeholder = 'Imported File Name';
            app.Button_UseSelectedSPDs = uibutton(app.Panel3, 'push');
            app.Button_UseSelectedSPDs.ButtonPushedFcn = createCallbackFcn(app, @Button_UseSelectedSPDsPushed, true);
            app.Button_UseSelectedSPDs.Text = 'Use Selected SPDs';
            app.UITable_ImportedFile = uitable(app.GridLayout3);
            app.UITable_ImportedFile.RowName = {};
            app.DataValidationTab = uitab(app.TabGroup);
            app.DataValidationTab.Title = 'Data Validation';
            app.GridLayout = uigridlayout(app.DataValidationTab);
            app.UIAxes_ChromDiagram = uiaxes(app.GridLayout);
            title(app.UIAxes_ChromDiagram, 'CIE Diagram')
            xlabel(app.UIAxes_ChromDiagram, 'x Chromaticity')
            ylabel(app.UIAxes_ChromDiagram, 'y Chromaticity')
            zlabel(app.UIAxes_ChromDiagram, 'Z')
            app.UIAxes_ImportedSPDs = uiaxes(app.GridLayout);
            title(app.UIAxes_ImportedSPDs, 'Imported SPD Channels')
            xlabel(app.UIAxes_ImportedSPDs, 'Wavelength (nm)')
            ylabel(app.UIAxes_ImportedSPDs, 'Radiant Watts / nm')
            zlabel(app.UIAxes_ImportedSPDs, 'Z')
            app.OptimizationSettingsTab = uitab(app.TabGroup);
            app.OptimizationSettingsTab.Title = 'Optimization Settings';
            app.GridLayout2 = uigridlayout(app.OptimizationSettingsTab);
            app.OptimizationGoalPanel = uipanel(app.GridLayout2);
            app.OptimizationGoalPanel.Title = 'Optimization Goal';
            app.MaximizeOrMinimizeLabel = uilabel(app.OptimizationGoalPanel);
            app.MaximizeOrMinimizeLabel.Text = 'Maximize Or Minimize?';
            app.DropDown_MaxOrMinGoal = uidropdown(app.OptimizationGoalPanel);
            app.WhichMetricLabel = uilabel(app.OptimizationGoalPanel);
            app.WhichMetricLabel.Text = 'Which Metric?';
            app.DropDown_MetricGoal = uidropdown(app.OptimizationGoalPanel);
            app.OptimizationSettingsPanel = uipanel(app.GridLayout2);
            app.OptimizationSettingsPanel.Title = 'Optimization Settings';
            app.MaxOptimizationIterationsLabel = uilabel(app.OptimizationSettingsPanel);
            app.MaxOptimizationIterationsLabel.Text = 'Max Optimization Iterations';
            app.EditField_MaxIterations = uieditfield(app.OptimizationSettingsPanel, 'numeric');
            app.EditField_MaxIterations.ValueDisplayFormat = '%.0f';
            app.Button_RunOptimization = uibutton(app.OptimizationSettingsPanel, 'push');
            app.Button_RunOptimization.ButtonPushedFcn = createCallbackFcn(app, @Button_RunOptimizationPushed, true);
            app.Button_RunOptimization.Text = 'Run Optimization';
            app.ProgramPathPanel = uipanel(app.GridLayout2);
            app.ProgramPathPanel.Title = 'Program Path';
            app.Button_SetOutputFolder = uibutton(app.ProgramPathPanel, 'push');
            app.Button_SetOutputFolder.ButtonPushedFcn = createCallbackFcn(app, @Button_SetOutputFolderPushed, true);
            app.Button_SetOutputFolder.Text = 'Set OutputFolder ';
            app.CurrentOutputFolderEditFieldLabel = uilabel(app.ProgramPathPanel);
            app.CurrentOutputFolderEditFieldLabel.Text = 'Current Output Folder';
            app.EditField_OutputFolder = uieditfield(app.ProgramPathPanel, 'text');
            app.EditField_OutputFolder.Editable = 'off';
            app.OutputExcelFileNameLabel = uilabel(app.ProgramPathPanel);
            app.OutputExcelFileNameLabel.Text = {'Output Excel File Name'; ''};
            app.EditField_OutputFileToSaveTo = uieditfield(app.ProgramPathPanel, 'text');
            app.OptimizationConstraintsPanel = uipanel(app.GridLayout2);
            app.OptimizationConstraintsPanel.Title = 'Optimization Constraints';
            app.UITable_Constraints = uitable(app.OptimizationConstraintsPanel);
            app.UITable_Constraints.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_Constraints.RowName = {};
            app.OptimizationResultsTab = uitab(app.TabGroup);
            app.OptimizationResultsTab.Title = 'Optimization Results';
            app.Panel4 = uipanel(app.OptimizationResultsTab);
            app.Panel4.Title = 'Panel4';
            app.UIAxes_TM30Vector = uiaxes(app.Panel4);
            title(app.UIAxes_TM30Vector, 'Title')
            xlabel(app.UIAxes_TM30Vector, 'X')
            ylabel(app.UIAxes_TM30Vector, 'Y')
            zlabel(app.UIAxes_TM30Vector, 'Z')
            app.UIAxes_TM30Bins = uiaxes(app.Panel4);
            title(app.UIAxes_TM30Bins, 'Title')
            xlabel(app.UIAxes_TM30Bins, 'X')
            ylabel(app.UIAxes_TM30Bins, 'Y')
            zlabel(app.UIAxes_TM30Bins, 'Z')
            app.UIAxes_OptimSPD = uiaxes(app.Panel4);
            title(app.UIAxes_OptimSPD, 'Title')
            xlabel(app.UIAxes_OptimSPD, 'X')
            ylabel(app.UIAxes_OptimSPD, 'Y')
            zlabel(app.UIAxes_OptimSPD, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetametricApp_Beta

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end